classdef MEANAPApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TestPipelineButton              matlab.ui.control.StateButton
        ViewOutputsButton               matlab.ui.control.StateButton
        LoadParametersButton            matlab.ui.control.StateButton
        SaveParametersButton            matlab.ui.control.StateButton
        Hyperlink                       matlab.ui.control.Hyperlink
        AllrequiredparameterssetLamp    matlab.ui.control.Lamp
        AllrequiredparameterssetLampLabel  matlab.ui.control.Label
        RunPipelineButton               matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        GENERALLabel                    matlab.ui.control.Label
        OutputFolderNameEditField       matlab.ui.control.EditField
        OutputFolderNameEditFieldLabel  matlab.ui.control.Label
        StartAnalysisStepEditField      matlab.ui.control.EditField
        StartAnalysisStepEditFieldLabel  matlab.ui.control.Label
        SpikeDataSelectButton           matlab.ui.control.StateButton
        PrevAnalysisSelectButton        matlab.ui.control.StateButton
        SpreadsheetRangeEditField       matlab.ui.control.EditField
        SpreadsheetRangeEditFieldLabel  matlab.ui.control.Label
        SpreadsheetSelectButton         matlab.ui.control.StateButton
        RawDataSelectButton             matlab.ui.control.StateButton
        OutputDataSelectButton          matlab.ui.control.StateButton
        HomeDirSelectButton             matlab.ui.control.StateButton
        ShowAdvancedSettingsCheckBox    matlab.ui.control.CheckBox
        OptionalStepstoRunListBox       matlab.ui.control.ListBox
        OptionalStepstoRunListBoxLabel  matlab.ui.control.Label
        UsePreviousAnalysisCheckBox     matlab.ui.control.CheckBox
        SpreadsheetFilenameEditField    matlab.ui.control.EditField
        SpreadsheetFilenameEditFieldLabel  matlab.ui.control.Label
        SpikeDataFolderEditField        matlab.ui.control.EditField
        SpikeDataFolderEditFieldLabel   matlab.ui.control.Label
        PreviousAnalysisFolderEditField  matlab.ui.control.EditField
        PreviousAnalysisFolderEditFieldLabel  matlab.ui.control.Label
        MEADataFolderEditField          matlab.ui.control.EditField
        MEADataFolderEditFieldLabel     matlab.ui.control.Label
        OutputDataFolderEditField       matlab.ui.control.EditField
        OutputDataFolderEditFieldLabel  matlab.ui.control.Label
        MEANAPFolderEditField           matlab.ui.control.EditField
        MEANAPFolderEditFieldLabel      matlab.ui.control.Label
        SpikeDetectionTab               matlab.ui.container.Tab
        SPIKEDETECTIONLabel             matlab.ui.control.Label
        RunspikecheckonpreviousspikedataCheckBox  matlab.ui.control.CheckBox
        SpikeMethodforAnalysisEditField  matlab.ui.control.EditField
        SpikeMethodforAnalysisEditFieldLabel  matlab.ui.control.Label
        WaveletCostEditField            matlab.ui.control.EditField
        WaveletCostEditFieldLabel       matlab.ui.control.Label
        WaveletsListBox                 matlab.ui.control.ListBox
        WaveletsListBoxLabel            matlab.ui.control.Label
        ThresholdsEditField             matlab.ui.control.EditField
        ThresholdsEditFieldLabel        matlab.ui.control.Label
        ChannelLayoutDropDown           matlab.ui.control.DropDown
        ChannelLayoutDropDownLabel      matlab.ui.control.Label
        PotentialDifferenceUnitEditField  matlab.ui.control.EditField
        PotentialDifferenceUnitEditFieldLabel  matlab.ui.control.Label
        DownSampleFrequencyEditField    matlab.ui.control.NumericEditField
        DownSampleFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        DetectSpikesCheckBox            matlab.ui.control.CheckBox
        AdvSpikeDetectionTab            matlab.ui.container.Tab
        RemoveinactivenodesCheckBox     matlab.ui.control.CheckBox
        MinactivitylevelspikessEditField  matlab.ui.control.NumericEditField
        MinactivitylevelspikessEditFieldLabel  matlab.ui.control.Label
        MultipletemplatemethodDropDown  matlab.ui.control.DropDown
        MultipletemplatemethodDropDownLabel  matlab.ui.control.Label
        AdaptmultipletemplatesCheckBox  matlab.ui.control.CheckBox
        ADVANCEDSPIKEDETECTIONLabel     matlab.ui.control.Label
        MaxNSpikestomaketemplateEditField  matlab.ui.control.NumericEditField
        MaxNSpikestomaketemplateEditFieldLabel  matlab.ui.control.Label
        SpikerefractoryperiodmsEditField  matlab.ui.control.NumericEditField
        SpikerefractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        TemplaterefractoryperiodmsEditField  matlab.ui.control.NumericEditField
        TemplaterefractoryperiodmsEditFieldLabel  matlab.ui.control.Label
        TimeUnitEditField               matlab.ui.control.EditField
        TimeUnitEditFieldLabel          matlab.ui.control.Label
        WaveletwidEditField             matlab.ui.control.EditField
        WaveletwidEditFieldLabel        matlab.ui.control.Label
        WaveletnScalesEditField         matlab.ui.control.NumericEditField
        WaveletnScalesEditFieldLabel    matlab.ui.control.Label
        HighpassfilterHzEditField       matlab.ui.control.NumericEditField
        HighpassfilterHzEditFieldLabel  matlab.ui.control.Label
        LowpassfilterHzEditField        matlab.ui.control.NumericEditField
        LowpassfilterHzEditFieldLabel   matlab.ui.control.Label
        WaveletmultiplierEditField      matlab.ui.control.NumericEditField
        WaveletmultiplierEditFieldLabel  matlab.ui.control.Label
        ChunklengthsecEditField         matlab.ui.control.NumericEditField
        ChunklengthsecEditFieldLabel    matlab.ui.control.Label
        RundetectioninchunksCheckBox    matlab.ui.control.CheckBox
        ThresholdcalculationwindowEditField  matlab.ui.control.EditField
        ThresholdcalculationwindowEditFieldLabel  matlab.ui.control.Label
        AdvBurstDetectionTab            matlab.ui.container.Tab
        BURSTDETECTIONLabel             matlab.ui.control.Label
        MinspikeperchannelburstEditField  matlab.ui.control.NumericEditField
        MinspikeperchannelburstEditFieldLabel  matlab.ui.control.Label
        SingleChanenlBurstDetectionDropDown  matlab.ui.control.DropDown
        SingleChanenlBurstDetectionDropDownLabel  matlab.ui.control.Label
        BakkumNetworkBurstThresholdEditField  matlab.ui.control.EditField
        BakkumNetworkBurstThresholdEditFieldLabel  matlab.ui.control.Label
        MinchannelpernetworkburstEditField  matlab.ui.control.NumericEditField
        MinchannelpernetworkburstEditFieldLabel  matlab.ui.control.Label
        MinspikepernetworkburstEditField  matlab.ui.control.NumericEditField
        MinspikepernetworkburstEditFieldLabel  matlab.ui.control.Label
        BurstDetectionMethodDropDown    matlab.ui.control.DropDown
        BurstDetectionMethodDropDownLabel  matlab.ui.control.Label
        ArtifactRemovalTab              matlab.ui.container.Tab
        ARTIFACTREMOVALLabel            matlab.ui.control.Label
        MaximumpositivepeakmultiplierEditField  matlab.ui.control.NumericEditField
        MaximumpositivepeakmultiplierEditFieldLabel  matlab.ui.control.Label
        MaximumspikepeakmultiplierEditField  matlab.ui.control.NumericEditField
        MaximumspikepeakmultiplierEditFieldLabel  matlab.ui.control.Label
        MinimumspikepeakmultiplierEditField  matlab.ui.control.NumericEditField
        MinimumspikepeakmultiplierEditFieldLabel  matlab.ui.control.Label
        RemoveartifactsCheckBox         matlab.ui.control.CheckBox
        ConnectivityTab                 matlab.ui.container.Tab
        CONNECTIVITYLabel               matlab.ui.control.Label
        MetricstorecomputeEditField     matlab.ui.control.EditField
        MetricstorecomputeEditFieldLabel  matlab.ui.control.Label
        RecomputemetricsCheckBox        matlab.ui.control.CheckBox
        NodecartographylagvaluesEditField  matlab.ui.control.EditField
        NodecartographylagvaluesEditFieldLabel  matlab.ui.control.Label
        AutomaticallysetnodecartographyboundariesCheckBox  matlab.ui.control.CheckBox
        AutomaticallysetnodecartographyboundariesperlagCheckBox  matlab.ui.control.CheckBox
        TruncationlengthsecEditField    matlab.ui.control.NumericEditField
        TruncationlengthsecEditFieldLabel  matlab.ui.control.Label
        TruncateRecordingCheckBox       matlab.ui.control.CheckBox
        PlotprobthresholdingchecksCheckBox  matlab.ui.control.CheckBox
        ProbthresholdingnumchecksEditField  matlab.ui.control.NumericEditField
        ProbthresholdingnumchecksLabel  matlab.ui.control.Label
        ProbthresholdingtailEditField   matlab.ui.control.NumericEditField
        ProbthresholdingtailLabel       matlab.ui.control.Label
        AdjacencymatrixtypeButtonGroup  matlab.ui.container.ButtonGroup
        BinaryButton                    matlab.ui.control.RadioButton
        WeightedButton                  matlab.ui.control.RadioButton
        ProbthresholdingiterationsEditField  matlab.ui.control.NumericEditField
        ProbthresholdingiterationsEditFieldLabel  matlab.ui.control.Label
        STTCLagmsEditField              matlab.ui.control.EditField
        STTCLagmsEditFieldLabel         matlab.ui.control.Label
        PlottingTab                     matlab.ui.container.Tab
        NodescalingpowerEditField       matlab.ui.control.NumericEditField
        NodescalingpowerEditFieldLabel  matlab.ui.control.Label
        NodesizescalingDropDown         matlab.ui.control.DropDown
        NodesizescalingDropDownLabel    matlab.ui.control.Label
        NodelayoutDropDown              matlab.ui.control.DropDown
        NodelayoutDropDownLabel         matlab.ui.control.Label
        MaximumnodesizeEditField        matlab.ui.control.NumericEditField
        MaximumnodesizeEditFieldLabel   matlab.ui.control.Label
        PLOTTINGLabel                   matlab.ui.control.Label
        CustomGroupOrderEditField       matlab.ui.control.EditField
        CustomGroupOrderEditFieldLabel  matlab.ui.control.Label
        RastercolormapDropDown          matlab.ui.control.DropDown
        RastercolormapDropDownLabel     matlab.ui.control.Label
        ShadeMetricDropDown             matlab.ui.control.DropDown
        ShadeMetricDropDownLabel        matlab.ui.control.Label
        RasterMapUpperPercentileEditField  matlab.ui.control.NumericEditField
        RasterMapUpperPercentileEditFieldLabel  matlab.ui.control.Label
        MinimumnodesizeEditField        matlab.ui.control.NumericEditField
        MinimumnodesizeEditFieldLabel   matlab.ui.control.Label
        IncludeNotBoxPlotsCheckBox      matlab.ui.control.CheckBox
        UseminmaxpergroupboundsCheckBox  matlab.ui.control.CheckBox
        UseminmaxallrecordingboundsCheckBox  matlab.ui.control.CheckBox
        UsetheoreticalboundsCheckBox    matlab.ui.control.CheckBox
        ColormapsettingsLabel           matlab.ui.control.Label
        IncludechannelnumberinplotsCheckBox  matlab.ui.control.CheckBox
        DisplayonlyonefigureCheckBox    matlab.ui.control.CheckBox
        DonotcompressSVGCheckBox        matlab.ui.control.CheckBox
        FigureformatsListBox            matlab.ui.control.ListBox
        FigureformatsListBoxLabel       matlab.ui.control.Label
        AdvPlottingTab                  matlab.ui.container.Tab
        EdgethresholdmethodDropDown     matlab.ui.control.DropDown
        EdgethresholdmethodDropDownLabel  matlab.ui.control.Label
        EdgeweightpercentileEditField   matlab.ui.control.NumericEditField
        EdgeweightpercentileEditFieldLabel  matlab.ui.control.Label
        KDEHeightEditField              matlab.ui.control.NumericEditField
        KDEwidthforonepointEditField    matlab.ui.control.NumericEditField
        KDEwidthforonepointEditFieldLabel  matlab.ui.control.Label
        KDEHeightEditFieldLabel         matlab.ui.control.Label
        EdgesubsamplingmethodDropDown   matlab.ui.control.DropDown
        EdgesubsamplingmethodDropDownLabel  matlab.ui.control.Label
        MinedgeweightEditField          matlab.ui.control.NumericEditField
        MinedgeweightEditFieldLabel     matlab.ui.control.Label
        MaximumedgesEditField           matlab.ui.control.NumericEditField
        MaximumedgesEditFieldLabel      matlab.ui.control.Label
        ADVANCEDPLOTTINGLabel           matlab.ui.control.Label
        ColorsTab                       matlab.ui.container.Tab
        COLORSLabel                     matlab.ui.control.Label
        colorUITable                    matlab.ui.control.Table
        AdvDimensionalityTab            matlab.ui.container.Tab
        DIMENSIONALITYLabel             matlab.ui.control.Label
        IncludeNMFcomponentsCheckBox    matlab.ui.control.CheckBox
        effRankCalculationMethodDropDown  matlab.ui.control.DropDown
        effRankCalculationMethodDropDownLabel  matlab.ui.control.Label
        effRankDownsamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        effRankDownsamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NMFDownsamplingFrequencyHzEditField  matlab.ui.control.NumericEditField
        NMFDownsamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        NodeCartographyTab              matlab.ui.container.Tab
        NODECARTOGRAPHYLabel            matlab.ui.control.Label
        connectorHubPartCoefEditField   matlab.ui.control.NumericEditField
        connectorHubPartCoefEditFieldLabel  matlab.ui.control.Label
        nonHubconnectorPartCoefEditField  matlab.ui.control.NumericEditField
        nonHubconnectorPartCoefEditFieldLabel  matlab.ui.control.Label
        proHubpartCoefEditField         matlab.ui.control.NumericEditField
        proHubpartCoefEditFieldLabel    matlab.ui.control.Label
        periPartCoefEditField           matlab.ui.control.NumericEditField
        periPartCoefEditFieldLabel      matlab.ui.control.Label
        hubBoundaryWMdDegEditField      matlab.ui.control.NumericEditField
        hubBoundaryWMdDegEditFieldLabel  matlab.ui.control.Label
        AdvConnectivityTab              matlab.ui.container.Tab
        ADVANCEDCONNECTIVITYLabel       matlab.ui.control.Label
        NetworkmetricstocalculateListBox  matlab.ui.control.ListBox
        NetworkmetricstocalculateListBoxLabel  matlab.ui.control.Label
        MinimumnumberofnodesEditField   matlab.ui.control.NumericEditField
        MinimumnumberofnodesEditFieldLabel  matlab.ui.control.Label
        ExcludeedgesbelowthresholdCheckBox  matlab.ui.control.CheckBox
        PipelineTab                     matlab.ui.container.Tab
        StimulationmodeCheckBox         matlab.ui.control.CheckBox
        PIPELINELabel                   matlab.ui.control.Label
        VerboseLevelDropDown            matlab.ui.control.DropDown
        VerboseLevelDropDownLabel       matlab.ui.control.Label
        TimeprocessesCheckBox           matlab.ui.control.CheckBox
        FileConversionTab               matlab.ui.container.Tab
        FILECONVERSIONLabel             matlab.ui.control.Label
        GroupNameEditField              matlab.ui.control.EditField
        GroupNameEditFieldLabel         matlab.ui.control.Label
        OneGenotypeCheckBox             matlab.ui.control.CheckBox
        DIVincludedCheckBox             matlab.ui.control.CheckBox
        BatchCSVNameEditField           matlab.ui.control.EditField
        BatchCSVNameEditFieldLabel      matlab.ui.control.Label
        FileConversionselectButton      matlab.ui.control.StateButton
        RunfileconversionButton         matlab.ui.control.StateButton
        DataFolderEditField             matlab.ui.control.EditField
        DataFolderEditFieldLabel        matlab.ui.control.Label
        FileTypeDropDown                matlab.ui.control.DropDown
        FileTypeDropDownLabel           matlab.ui.control.Label
        SpreadsheetTab                  matlab.ui.container.Tab
        SPREADSHEETLabel                matlab.ui.control.Label
        CreatespreadsheetButton         matlab.ui.control.StateButton
        RawDataFolderEditField_2        matlab.ui.control.EditField
        RawDataFolderEditField_2Label   matlab.ui.control.Label
        LoadspreadsheetButton           matlab.ui.control.StateButton
        SavespreadsheetButton           matlab.ui.control.StateButton
        csvTable                        matlab.ui.control.Table
        SpreadsheetFilepathEditField    matlab.ui.control.EditField
        SpreadsheetFilepathEditFieldLabel  matlab.ui.control.Label
        CATNAPTab                       matlab.ui.container.Tab
        TimeafterpeaksEditField         matlab.ui.control.NumericEditField
        TimeafterpeaksEditFieldLabel    matlab.ui.control.Label
        TimebeforepeaksEditField        matlab.ui.control.NumericEditField
        TimebeforepeaksEditFieldLabel   matlab.ui.control.Label
        DenoisingthresholdEditField     matlab.ui.control.NumericEditField
        DenoisingthresholdEditFieldLabel  matlab.ui.control.Label
        DenoisingparametersLabel        matlab.ui.control.Label
        CellstoplotperrecordingEditField  matlab.ui.control.EditField
        CellstoplotperrecordingEditFieldLabel  matlab.ui.control.Label
        RemovenodeswithnopeaksCheckBox  matlab.ui.control.CheckBox
        CATNAPLabel                     matlab.ui.control.Label
        RedodenoisingCheckBox           matlab.ui.control.CheckBox
        ActivityDropDown                matlab.ui.control.DropDown
        ActivityDropDownLabel           matlab.ui.control.Label
        PythonpathEditField             matlab.ui.control.EditField
        PythonpathEditFieldLabel        matlab.ui.control.Label
        StimulationTab                  matlab.ui.container.Tab
        LaunchstimdetectionappButton    matlab.ui.control.StateButton
        StimignorespikeswindowsEditField  matlab.ui.control.EditField
        StimignorespikeswindowsEditFieldLabel  matlab.ui.control.Label
        StimdurationforplotssEditField  matlab.ui.control.NumericEditField
        StimdurationforplotssEditFieldLabel  matlab.ui.control.Label
        PoststimwindowsEditField        matlab.ui.control.EditField
        PoststimwindowsEditFieldLabel   matlab.ui.control.Label
        PrestimwindowsEditField         matlab.ui.control.EditField
        PrestimwindowsEditFieldLabel    matlab.ui.control.Label
        StimdurationsEditField          matlab.ui.control.NumericEditField
        StimdurationsEditFieldLabel     matlab.ui.control.Label
        DetectionthresholdmultiplierEditField  matlab.ui.control.NumericEditField
        DetectionthresholdmultiplierEditFieldLabel  matlab.ui.control.Label
        AutomaticstimdetectionCheckBox  matlab.ui.control.CheckBox
        StimrefractoryperiodsEditField  matlab.ui.control.NumericEditField
        StimrefractoryperiodsEditFieldLabel  matlab.ui.control.Label
        StimdetectionmethodDropDown     matlab.ui.control.DropDown
        StimdetectionmethodDropDownLabel  matlab.ui.control.Label
        StimulationLabel                matlab.ui.control.Label
        MergeDIVsTab                    matlab.ui.container.Tab
        RunMergeButton                  matlab.ui.control.Button
        OutputFolderNameEdit            matlab.ui.control.EditField
        OutputFolderNameLabel           matlab.ui.control.Label
        BrowseOutputButton              matlab.ui.control.Button
        OutputFolderEdit                matlab.ui.control.EditField
        OutputFolderLabel               matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        InputListBox                    matlab.ui.control.ListBox
        SelectedInputsListBoxLabel      matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        MEANAPStatusTextArea            matlab.ui.control.TextArea
        MEANAPStatusTextAreaLabel       matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        SpikeDetectionMenu              matlab.ui.container.Menu
        ConnectivityMenu                matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        RecordingviewerMenu             matlab.ui.container.Menu
        StatsviewerMenu                 matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        InputPaths = {}
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            % Get the selected item text
            selectedItem = app.InputListBox.Value;
            
            % Check if something is selected
            if isempty(selectedItem)
                uialert(app.UIFigure, 'Please select an item to remove.', 'No Selection');
                return;
            end
            
            % Find the index of this item in our InputPaths array
            selectedIndex = find(strcmp(app.InputPaths, selectedItem));
            
            % Check if we found the item
            if ~isempty(selectedIndex)
                % Remove the selected path
                app.InputPaths(selectedIndex) = [];
                
                % Update the ListBox
                app.InputListBox.Items = app.InputPaths;
                
                % Adjust the selection
                if ~isempty(app.InputPaths)
                    if selectedIndex > length(app.InputPaths)
                        app.InputListBox.Value = app.InputPaths{end};
                    else
                        app.InputListBox.Value = app.InputPaths{selectedIndex};
                    end
                else
                    % No items left, clear selection with empty cell array
                    app.InputListBox.Value = {};
                end
            else
                uialert(app.UIFigure, 'Selected item not found in the list. Refreshing list.', 'Error');
                
                % Resync the listbox with the data
                app.InputListBox.Items = app.InputPaths;
                if isempty(app.InputPaths)
                    app.InputListBox.Value = {};
                else
                    app.InputListBox.Value = '';
                end
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Store current figure handle
            mainFig = app.UIFigure;
            
            % Make main figure modal before showing dialog
            mainFig.WindowStyle = 'modal';
            
            % Open folder selection dialog
            folderPath = uigetdir('', 'Select a folder');

            % Restore main figure to normal
            mainFig.WindowStyle = 'normal';
           
            % Check if user cancelled
            if isequal(folderPath, 0)
                return;
            end
            
            % Add to list if not already there
            if ~ismember(folderPath, app.InputPaths)
                app.InputPaths{end+1} = folderPath;
                
                % Update the ListBox
                app.InputListBox.Items = app.InputPaths;
            end
        end

        % Button pushed function: RunMergeButton
        function RunMergeButtonPushed(app, event)
            % Store current figure handle
            mainFig = app.UIFigure;
            
            % Make main figure modal before showing dialog
            mainFig.WindowStyle = 'modal';

            % Restore main figure to normal
            mainFig.WindowStyle = 'normal';
            
            % Check if input paths are selected
            if isempty(app.InputPaths)
                uialert(app.UIFigure, 'Please add at least one input path.', 'No Input Paths');
                return;
            end
            
            % Check if output folder is specified
            outputFolder = app.OutputFolderEdit.Value;
            if isempty(outputFolder)
                uialert(app.UIFigure, 'Please specify an output folder.', 'No Output Folder');
                return;
            end
            
            % Check if output folder name is specified
            folderName = app.OutputFolderNameEdit.Value;
            if isempty(folderName)
                uialert(app.UIFigure, 'Please specify an output folder name.', 'No Folder Name');
                return;
            end
            
            % Create the full output path
            fullOutputPath = fullfile(outputFolder, folderName);
            
            try
                % Run the mergeDIV function with the input and output paths
                mergeDIV(app.InputPaths, fullOutputPath);
                
                % Show success message
                uialert(app.UIFigure, 'Merge completed successfully!', 'Success', 'Icon', 'success');
            catch e
                % Show error message if the merge failed
                errorMsg = sprintf('Merge failed: %s', e.message);
                uialert(app.UIFigure, errorMsg, 'Error', 'Icon', 'error');
            end
        end

        % Button pushed function: BrowseOutputButton
        function BrowseOutputButtonPushed(app, event)
            % Store current figure handle
            mainFig = app.UIFigure;
            
            % Make main figure modal before showing dialog
            mainFig.WindowStyle = 'modal';
            
            % Open folder selection dialog
            outputPath = uigetdir('', 'Select Output Folder');

            % Restore main figure to normal
            mainFig.WindowStyle = 'normal';
            
            % Check if user cancelled the dialog
            if isequal(outputPath, 0)
                return;
            end
            
            % Update the OutputFolderEdit field
            app.OutputFolderEdit.Value = outputPath;
        end

        % Value changed function: OutputFolderEdit
        function OutputFolderEditValueChanged(app, event)
            % This function is optional, but can be used to validate the path
            % or perform other actions when the output folder is manually changed
            outputPath = app.OutputFolderEdit.Value;
            
            % You can add validation here if needed
            % For example, check if the path exists
            if ~exist(outputPath, 'dir')
                % Path doesn't exist, show warning but don't change the value
                uialert(app.UIFigure, 'The specified output folder does not exist.', 'Invalid Path', 'Icon', 'warning');
            end
            
        end

        % Value changed function: OutputFolderNameEdit
        function OutputFolderNameEditValueChanged(app, event)
            % This function is optional, but can be used to validate the folder name
            % or perform other actions when the folder name is changed
            folderName = app.OutputFolderNameEdit.Value;
            
            % You can add validation here if needed
            % For example, check if the name contains invalid characters
            invalidChars = '\/:|<>*?"';
            if any(ismember(folderName, invalidChars))
                % Name contains invalid characters, show warning
                uialert(app.UIFigure, 'The folder name contains invalid characters.', 'Invalid Name', 'Icon', 'warning');
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {570, 570};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {432, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.TabGroup.Tooltip = {''};
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Tooltip = {''};
            app.GeneralTab.Title = 'General';
            app.MEANAPFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.MEANAPFolderEditFieldLabel.Text = 'MEA-NAP Folder';
            app.MEANAPFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.MEANAPFolderEditField.Tooltip = {'Folder where the MEA-NAP code is located'};
            app.OutputDataFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputDataFolderEditFieldLabel.Text = 'Output Data Folder';
            app.OutputDataFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.OutputDataFolderEditField.Tooltip = {'Folder where you want to save the pipeline outputs (optional)'};
            app.MEADataFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.MEADataFolderEditFieldLabel.Text = 'MEA Data Folder';
            app.MEADataFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.MEADataFolderEditField.Tooltip = {'Folder where your raw data is located'};
            app.PreviousAnalysisFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.PreviousAnalysisFolderEditFieldLabel.Text = 'Previous Analysis Folder';
            app.PreviousAnalysisFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.SpikeDataFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.SpikeDataFolderEditFieldLabel.Text = 'Spike Data Folder';
            app.SpikeDataFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.SpreadsheetFilenameEditFieldLabel = uilabel(app.GeneralTab);
            app.SpreadsheetFilenameEditFieldLabel.Text = 'Spreadsheet Filename';
            app.SpreadsheetFilenameEditField = uieditfield(app.GeneralTab, 'text');
            app.SpreadsheetFilenameEditField.Tooltip = {'Path to the spreadsheet containing recording names, DIV, and group information'};
            app.UsePreviousAnalysisCheckBox = uicheckbox(app.GeneralTab);
            app.UsePreviousAnalysisCheckBox.Text = 'Use Previous Analysis';
            app.OptionalStepstoRunListBoxLabel = uilabel(app.GeneralTab);
            app.OptionalStepstoRunListBoxLabel.Text = 'Optional Steps to Run';
            app.OptionalStepstoRunListBox = uilistbox(app.GeneralTab);
            app.OptionalStepstoRunListBox.Items = {'None', 'Stats', 'Combine Plots'};
            app.OptionalStepstoRunListBox.Multiselect = 'on';
            app.OptionalStepstoRunListBox.Tooltip = {'Hold control when clicking to select multiple steps'};
            app.OptionalStepstoRunListBox.Value = {'None'};
            app.ShowAdvancedSettingsCheckBox = uicheckbox(app.GeneralTab);
            app.ShowAdvancedSettingsCheckBox.Text = 'Show Advanced Settings';
            app.HomeDirSelectButton = uibutton(app.GeneralTab, 'state');
            app.HomeDirSelectButton.Text = 'select';
            app.OutputDataSelectButton = uibutton(app.GeneralTab, 'state');
            app.OutputDataSelectButton.Text = 'select';
            app.RawDataSelectButton = uibutton(app.GeneralTab, 'state');
            app.RawDataSelectButton.Text = 'select';
            app.SpreadsheetSelectButton = uibutton(app.GeneralTab, 'state');
            app.SpreadsheetSelectButton.Text = 'select';
            app.SpreadsheetRangeEditFieldLabel = uilabel(app.GeneralTab);
            app.SpreadsheetRangeEditFieldLabel.Text = 'Spreadsheet Range';
            app.SpreadsheetRangeEditField = uieditfield(app.GeneralTab, 'text');
            app.SpreadsheetRangeEditField.Value = '[2, Inf]';
            app.PrevAnalysisSelectButton = uibutton(app.GeneralTab, 'state');
            app.PrevAnalysisSelectButton.Text = 'select';
            app.SpikeDataSelectButton = uibutton(app.GeneralTab, 'state');
            app.SpikeDataSelectButton.Text = 'select';
            app.StartAnalysisStepEditFieldLabel = uilabel(app.GeneralTab);
            app.StartAnalysisStepEditFieldLabel.Text = 'Start Analysis Step';
            app.StartAnalysisStepEditField = uieditfield(app.GeneralTab, 'text');
            app.StartAnalysisStepEditField.Tooltip = {'Which step to start analysis on. Can be a number or number followed by letter, eg. 4A'};
            app.StartAnalysisStepEditField.Value = '1';
            app.OutputFolderNameEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputFolderNameEditFieldLabel.Text = 'Output Folder Name';
            app.OutputFolderNameEditField = uieditfield(app.GeneralTab, 'text');
            app.GENERALLabel = uilabel(app.GeneralTab);
            app.GENERALLabel.Text = 'GENERAL';
            app.SpikeDetectionTab = uitab(app.TabGroup);
            app.SpikeDetectionTab.Title = 'Spike Detection';
            app.DetectSpikesCheckBox = uicheckbox(app.SpikeDetectionTab);
            app.DetectSpikesCheckBox.Text = 'Detect Spikes';
            app.DetectSpikesCheckBox.Value = true;
            app.SamplingFrequencyEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.SpikeDetectionTab, 'numeric');
            app.SamplingFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.SamplingFrequencyEditField.Value = 12500;
            app.DownSampleFrequencyEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.DownSampleFrequencyEditFieldLabel.Text = 'Down Sample Frequency';
            app.DownSampleFrequencyEditField = uieditfield(app.SpikeDetectionTab, 'numeric');
            app.DownSampleFrequencyEditField.ValueDisplayFormat = '%.0f';
            app.DownSampleFrequencyEditField.Value = 12500;
            app.PotentialDifferenceUnitEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.PotentialDifferenceUnitEditFieldLabel.Text = 'Potential Difference Unit';
            app.PotentialDifferenceUnitEditField = uieditfield(app.SpikeDetectionTab, 'text');
            app.PotentialDifferenceUnitEditField.Value = 'V';
            app.ChannelLayoutDropDownLabel = uilabel(app.SpikeDetectionTab);
            app.ChannelLayoutDropDownLabel.Text = 'Channel Layout';
            app.ChannelLayoutDropDown = uidropdown(app.SpikeDetectionTab);
            app.ChannelLayoutDropDown.Items = {'MCS60', 'MCS60old', 'Axion16', 'Axion64', 'Custom'};
            app.ChannelLayoutDropDown.Value = 'Axion64';
            app.ThresholdsEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.ThresholdsEditFieldLabel.Text = 'Thresholds';
            app.ThresholdsEditField = uieditfield(app.SpikeDetectionTab, 'text');
            app.ThresholdsEditField.Value = '[4, 5]';
            app.WaveletsListBoxLabel = uilabel(app.SpikeDetectionTab);
            app.WaveletsListBoxLabel.Text = 'Wavelets';
            app.WaveletsListBox = uilistbox(app.SpikeDetectionTab);
            app.WaveletsListBox.Items = {'None', 'bior1.5', 'bior1.3', 'db2', 'swtteo', 'custom'};
            app.WaveletsListBox.Multiselect = 'on';
            app.WaveletsListBox.Value = {'bior1.5'};
            app.WaveletCostEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.WaveletCostEditFieldLabel.Text = 'Wavelet Cost';
            app.WaveletCostEditField = uieditfield(app.SpikeDetectionTab, 'text');
            app.WaveletCostEditField.Value = '-0.12';
            app.SpikeMethodforAnalysisEditFieldLabel = uilabel(app.SpikeDetectionTab);
            app.SpikeMethodforAnalysisEditFieldLabel.Text = 'Spike Method for Analysis';
            app.SpikeMethodforAnalysisEditField = uieditfield(app.SpikeDetectionTab, 'text');
            app.SpikeMethodforAnalysisEditField.Value = 'bior1p5';
            app.RunspikecheckonpreviousspikedataCheckBox = uicheckbox(app.SpikeDetectionTab);
            app.RunspikecheckonpreviousspikedataCheckBox.Text = 'Run spike check on previous spike data';
            app.SPIKEDETECTIONLabel = uilabel(app.SpikeDetectionTab);
            app.SPIKEDETECTIONLabel.Text = 'SPIKE DETECTION';
            app.AdvSpikeDetectionTab = uitab(app.TabGroup);
            app.AdvSpikeDetectionTab.Title = 'Adv. Spike Detection';
            app.ThresholdcalculationwindowEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.ThresholdcalculationwindowEditFieldLabel.Text = 'Threshold calculation window';
            app.ThresholdcalculationwindowEditField = uieditfield(app.AdvSpikeDetectionTab, 'text');
            app.ThresholdcalculationwindowEditField.Value = '[0, 1]';
            app.RundetectioninchunksCheckBox = uicheckbox(app.AdvSpikeDetectionTab);
            app.RundetectioninchunksCheckBox.Text = 'Run detection in chunks';
            app.ChunklengthsecEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.ChunklengthsecEditFieldLabel.Text = 'Chunk length (sec)';
            app.ChunklengthsecEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.WaveletmultiplierEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.WaveletmultiplierEditFieldLabel.Text = 'Wavelet multiplier';
            app.WaveletmultiplierEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.WaveletmultiplierEditField.Value = 3.5;
            app.LowpassfilterHzEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.LowpassfilterHzEditFieldLabel.Text = 'Low pass filter (Hz)';
            app.LowpassfilterHzEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.LowpassfilterHzEditField.Value = 600;
            app.HighpassfilterHzEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.HighpassfilterHzEditFieldLabel.Text = 'High pass filter (Hz)';
            app.HighpassfilterHzEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.HighpassfilterHzEditField.Value = 8000;
            app.WaveletnScalesEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.WaveletnScalesEditFieldLabel.Text = 'Wavelet nScales';
            app.WaveletnScalesEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.WaveletnScalesEditField.Value = 5;
            app.WaveletwidEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.WaveletwidEditFieldLabel.Text = 'Wavelet wid';
            app.WaveletwidEditField = uieditfield(app.AdvSpikeDetectionTab, 'text');
            app.WaveletwidEditField.Value = '[0.4, 0.8]';
            app.TimeUnitEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.TimeUnitEditFieldLabel.Text = 'Time Unit';
            app.TimeUnitEditField = uieditfield(app.AdvSpikeDetectionTab, 'text');
            app.TimeUnitEditField.Value = 's';
            app.TemplaterefractoryperiodmsEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.TemplaterefractoryperiodmsEditFieldLabel.Text = 'Template refractory period (ms)';
            app.TemplaterefractoryperiodmsEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.TemplaterefractoryperiodmsEditField.Value = 2;
            app.SpikerefractoryperiodmsEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.SpikerefractoryperiodmsEditFieldLabel.Text = 'Spike refractory period (ms)';
            app.SpikerefractoryperiodmsEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.SpikerefractoryperiodmsEditField.Value = 1;
            app.MaxNSpikestomaketemplateEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.MaxNSpikestomaketemplateEditFieldLabel.Text = 'MaxNSpikes to make template';
            app.MaxNSpikestomaketemplateEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.MaxNSpikestomaketemplateEditField.ValueDisplayFormat = '%.0f';
            app.MaxNSpikestomaketemplateEditField.Value = 10000;
            app.ADVANCEDSPIKEDETECTIONLabel = uilabel(app.AdvSpikeDetectionTab);
            app.ADVANCEDSPIKEDETECTIONLabel.Text = 'ADVANCED SPIKE DETECTION';
            app.AdaptmultipletemplatesCheckBox = uicheckbox(app.AdvSpikeDetectionTab);
            app.AdaptmultipletemplatesCheckBox.Text = 'Adapt multiple templates';
            app.MultipletemplatemethodDropDownLabel = uilabel(app.AdvSpikeDetectionTab);
            app.MultipletemplatemethodDropDownLabel.Text = 'Multiple template method';
            app.MultipletemplatemethodDropDown = uidropdown(app.AdvSpikeDetectionTab);
            app.MultipletemplatemethodDropDown.Items = {'amplitudeAndWidthAndSymmetry', 'spikeWidthAndAmplitude', 'PCA'};
            app.MultipletemplatemethodDropDown.Value = 'amplitudeAndWidthAndSymmetry';
            app.MinactivitylevelspikessEditFieldLabel = uilabel(app.AdvSpikeDetectionTab);
            app.MinactivitylevelspikessEditFieldLabel.Text = 'Min activity level (spikes/s)';
            app.MinactivitylevelspikessEditField = uieditfield(app.AdvSpikeDetectionTab, 'numeric');
            app.MinactivitylevelspikessEditField.Value = 0.01;
            app.RemoveinactivenodesCheckBox = uicheckbox(app.AdvSpikeDetectionTab);
            app.RemoveinactivenodesCheckBox.Tooltip = {'This will remove nodes below min activity level from all analysis'};
            app.RemoveinactivenodesCheckBox.Text = 'Remove inactive nodes';
            app.AdvBurstDetectionTab = uitab(app.TabGroup);
            app.AdvBurstDetectionTab.Title = 'Adv. Burst Detection';
            app.BurstDetectionMethodDropDownLabel = uilabel(app.AdvBurstDetectionTab);
            app.BurstDetectionMethodDropDownLabel.Text = 'Burst Detection Method';
            app.BurstDetectionMethodDropDown = uidropdown(app.AdvBurstDetectionTab);
            app.BurstDetectionMethodDropDown.Items = {'Bakkum', 'Manuel', 'LogISI', 'nno'};
            app.BurstDetectionMethodDropDown.Value = 'Bakkum';
            app.MinspikepernetworkburstEditFieldLabel = uilabel(app.AdvBurstDetectionTab);
            app.MinspikepernetworkburstEditFieldLabel.Text = 'Min spike per network burst';
            app.MinspikepernetworkburstEditField = uieditfield(app.AdvBurstDetectionTab, 'numeric');
            app.MinspikepernetworkburstEditField.Value = 10;
            app.MinchannelpernetworkburstEditFieldLabel = uilabel(app.AdvBurstDetectionTab);
            app.MinchannelpernetworkburstEditFieldLabel.Text = 'Min channel per network burst';
            app.MinchannelpernetworkburstEditField = uieditfield(app.AdvBurstDetectionTab, 'numeric');
            app.MinchannelpernetworkburstEditField.Value = 3;
            app.BakkumNetworkBurstThresholdEditFieldLabel = uilabel(app.AdvBurstDetectionTab);
            app.BakkumNetworkBurstThresholdEditFieldLabel.Text = 'Bakkum Network Burst Threshold';
            app.BakkumNetworkBurstThresholdEditField = uieditfield(app.AdvBurstDetectionTab, 'text');
            app.BakkumNetworkBurstThresholdEditField.Value = 'automatic';
            app.SingleChanenlBurstDetectionDropDownLabel = uilabel(app.AdvBurstDetectionTab);
            app.SingleChanenlBurstDetectionDropDownLabel.Text = 'Single Chanenl Burst Detection';
            app.SingleChanenlBurstDetectionDropDown = uidropdown(app.AdvBurstDetectionTab);
            app.SingleChanenlBurstDetectionDropDown.Items = {'Bakkum', ''};
            app.SingleChanenlBurstDetectionDropDown.Value = 'Bakkum';
            app.MinspikeperchannelburstEditFieldLabel = uilabel(app.AdvBurstDetectionTab);
            app.MinspikeperchannelburstEditFieldLabel.Text = 'Min spike per channel burst';
            app.MinspikeperchannelburstEditField = uieditfield(app.AdvBurstDetectionTab, 'numeric');
            app.MinspikeperchannelburstEditField.Value = 10;
            app.BURSTDETECTIONLabel = uilabel(app.AdvBurstDetectionTab);
            app.BURSTDETECTIONLabel.Text = 'BURST DETECTION';
            app.ArtifactRemovalTab = uitab(app.TabGroup);
            app.ArtifactRemovalTab.Title = 'Artifact Removal';
            app.RemoveartifactsCheckBox = uicheckbox(app.ArtifactRemovalTab);
            app.RemoveartifactsCheckBox.Text = 'Remove artifacts';
            app.MinimumspikepeakmultiplierEditFieldLabel = uilabel(app.ArtifactRemovalTab);
            app.MinimumspikepeakmultiplierEditFieldLabel.Text = 'Minimum spike peak multiplier ';
            app.MinimumspikepeakmultiplierEditField = uieditfield(app.ArtifactRemovalTab, 'numeric');
            app.MinimumspikepeakmultiplierEditField.Value = -5;
            app.MaximumspikepeakmultiplierEditFieldLabel = uilabel(app.ArtifactRemovalTab);
            app.MaximumspikepeakmultiplierEditFieldLabel.Text = 'Maximum spike peak multiplier ';
            app.MaximumspikepeakmultiplierEditField = uieditfield(app.ArtifactRemovalTab, 'numeric');
            app.MaximumspikepeakmultiplierEditField.Value = -100;
            app.MaximumpositivepeakmultiplierEditFieldLabel = uilabel(app.ArtifactRemovalTab);
            app.MaximumpositivepeakmultiplierEditFieldLabel.Text = 'Maximum positive peak multiplier';
            app.MaximumpositivepeakmultiplierEditField = uieditfield(app.ArtifactRemovalTab, 'numeric');
            app.MaximumpositivepeakmultiplierEditField.Value = 15;
            app.ARTIFACTREMOVALLabel = uilabel(app.ArtifactRemovalTab);
            app.ARTIFACTREMOVALLabel.Text = 'ARTIFACT REMOVAL';
            app.ConnectivityTab = uitab(app.TabGroup);
            app.ConnectivityTab.Title = 'Connectivity';
            app.STTCLagmsEditFieldLabel = uilabel(app.ConnectivityTab);
            app.STTCLagmsEditFieldLabel.Text = 'STTC Lag (ms)';
            app.STTCLagmsEditField = uieditfield(app.ConnectivityTab, 'text');
            app.STTCLagmsEditField.Value = '[10, 25, 50]';
            app.ProbthresholdingiterationsEditFieldLabel = uilabel(app.ConnectivityTab);
            app.ProbthresholdingiterationsEditFieldLabel.Text = 'Prob. thresholding iterations';
            app.ProbthresholdingiterationsEditField = uieditfield(app.ConnectivityTab, 'numeric');
            app.ProbthresholdingiterationsEditField.Value = 200;
            app.AdjacencymatrixtypeButtonGroup = uibuttongroup(app.ConnectivityTab);
            app.AdjacencymatrixtypeButtonGroup.Title = 'Adjacency matrix type';
            app.WeightedButton = uiradiobutton(app.AdjacencymatrixtypeButtonGroup);
            app.WeightedButton.Text = 'Weighted';
            app.WeightedButton.Value = true;
            app.BinaryButton = uiradiobutton(app.AdjacencymatrixtypeButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.ProbthresholdingtailLabel = uilabel(app.ConnectivityTab);
            app.ProbthresholdingtailLabel.Text = 'Prob. thresholding tail';
            app.ProbthresholdingtailEditField = uieditfield(app.ConnectivityTab, 'numeric');
            app.ProbthresholdingtailEditField.Value = 0.05;
            app.ProbthresholdingnumchecksLabel = uilabel(app.ConnectivityTab);
            app.ProbthresholdingnumchecksLabel.Text = 'Prob. thresholding num. checks';
            app.ProbthresholdingnumchecksEditField = uieditfield(app.ConnectivityTab, 'numeric');
            app.ProbthresholdingnumchecksEditField.Value = 5;
            app.PlotprobthresholdingchecksCheckBox = uicheckbox(app.ConnectivityTab);
            app.PlotprobthresholdingchecksCheckBox.Text = 'Plot prob. thresholding checks';
            app.PlotprobthresholdingchecksCheckBox.Value = true;
            app.TruncateRecordingCheckBox = uicheckbox(app.ConnectivityTab);
            app.TruncateRecordingCheckBox.Text = 'Truncate Recording';
            app.TruncationlengthsecEditFieldLabel = uilabel(app.ConnectivityTab);
            app.TruncationlengthsecEditFieldLabel.Text = 'Truncation length  (sec)';
            app.TruncationlengthsecEditField = uieditfield(app.ConnectivityTab, 'numeric');
            app.TruncationlengthsecEditField.Value = 120;
            app.AutomaticallysetnodecartographyboundariesperlagCheckBox = uicheckbox(app.ConnectivityTab);
            app.AutomaticallysetnodecartographyboundariesperlagCheckBox.Text = 'Automatically set node cartography boundaries per lag';
            app.AutomaticallysetnodecartographyboundariesperlagCheckBox.Value = true;
            app.AutomaticallysetnodecartographyboundariesCheckBox = uicheckbox(app.ConnectivityTab);
            app.AutomaticallysetnodecartographyboundariesCheckBox.Text = 'Automatically set node cartography boundaries';
            app.AutomaticallysetnodecartographyboundariesCheckBox.Value = true;
            app.NodecartographylagvaluesEditFieldLabel = uilabel(app.ConnectivityTab);
            app.NodecartographylagvaluesEditFieldLabel.Text = 'Node cartography lag values';
            app.NodecartographylagvaluesEditField = uieditfield(app.ConnectivityTab, 'text');
            app.NodecartographylagvaluesEditField.Value = '[10, 25, 50]';
            app.RecomputemetricsCheckBox = uicheckbox(app.ConnectivityTab);
            app.RecomputemetricsCheckBox.Text = 'Recompute metrics';
            app.MetricstorecomputeEditFieldLabel = uilabel(app.ConnectivityTab);
            app.MetricstorecomputeEditFieldLabel.Text = 'Metrics to recompute';
            app.MetricstorecomputeEditField = uieditfield(app.ConnectivityTab, 'text');
            app.MetricstorecomputeEditField.Value = 'all';
            app.CONNECTIVITYLabel = uilabel(app.ConnectivityTab);
            app.CONNECTIVITYLabel.Text = 'CONNECTIVITY';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.FigureformatsListBoxLabel = uilabel(app.PlottingTab);
            app.FigureformatsListBoxLabel.Text = 'Figure formats';
            app.FigureformatsListBox = uilistbox(app.PlottingTab);
            app.FigureformatsListBox.Items = {'png', 'svg', 'fig'};
            app.FigureformatsListBox.Multiselect = 'on';
            app.FigureformatsListBox.Value = {'png'};
            app.DonotcompressSVGCheckBox = uicheckbox(app.PlottingTab);
            app.DonotcompressSVGCheckBox.Text = 'Do not compress SVG';
            app.DonotcompressSVGCheckBox.Value = true;
            app.DisplayonlyonefigureCheckBox = uicheckbox(app.PlottingTab);
            app.DisplayonlyonefigureCheckBox.Text = 'Display only one figure';
            app.DisplayonlyonefigureCheckBox.Value = true;
            app.IncludechannelnumberinplotsCheckBox = uicheckbox(app.PlottingTab);
            app.IncludechannelnumberinplotsCheckBox.Text = 'Include channel number in plots';
            app.ColormapsettingsLabel = uilabel(app.PlottingTab);
            app.ColormapsettingsLabel.Text = 'Colormap settings';
            app.UsetheoreticalboundsCheckBox = uicheckbox(app.PlottingTab);
            app.UsetheoreticalboundsCheckBox.Text = 'Use theoretical bounds';
            app.UseminmaxallrecordingboundsCheckBox = uicheckbox(app.PlottingTab);
            app.UseminmaxallrecordingboundsCheckBox.Text = 'Use min max all recording bounds';
            app.UseminmaxpergroupboundsCheckBox = uicheckbox(app.PlottingTab);
            app.UseminmaxpergroupboundsCheckBox.Text = 'Use min max per group bounds';
            app.IncludeNotBoxPlotsCheckBox = uicheckbox(app.PlottingTab);
            app.IncludeNotBoxPlotsCheckBox.Text = 'Include NotBoxPlots';
            app.MinimumnodesizeEditFieldLabel = uilabel(app.PlottingTab);
            app.MinimumnodesizeEditFieldLabel.Text = 'Minimum node size';
            app.MinimumnodesizeEditField = uieditfield(app.PlottingTab, 'numeric');
            app.MinimumnodesizeEditField.Value = 0.01;
            app.RasterMapUpperPercentileEditFieldLabel = uilabel(app.PlottingTab);
            app.RasterMapUpperPercentileEditFieldLabel.Text = 'Raster Map Upper Percentile';
            app.RasterMapUpperPercentileEditField = uieditfield(app.PlottingTab, 'numeric');
            app.RasterMapUpperPercentileEditField.Value = 99;
            app.ShadeMetricDropDownLabel = uilabel(app.PlottingTab);
            app.ShadeMetricDropDownLabel.Text = 'Shade Metric';
            app.ShadeMetricDropDown = uidropdown(app.PlottingTab);
            app.ShadeMetricDropDown.Items = {'sem', 'std'};
            app.ShadeMetricDropDown.Value = 'sem';
            app.RastercolormapDropDownLabel = uilabel(app.PlottingTab);
            app.RastercolormapDropDownLabel.Text = 'Raster colormap';
            app.RastercolormapDropDown = uidropdown(app.PlottingTab);
            app.RastercolormapDropDown.Items = {'parula', 'gray'};
            app.RastercolormapDropDown.Value = 'parula';
            app.CustomGroupOrderEditFieldLabel = uilabel(app.PlottingTab);
            app.CustomGroupOrderEditFieldLabel.Text = 'Custom Group Order';
            app.CustomGroupOrderEditField = uieditfield(app.PlottingTab, 'text');
            app.PLOTTINGLabel = uilabel(app.PlottingTab);
            app.PLOTTINGLabel.Text = 'PLOTTING';
            app.MaximumnodesizeEditFieldLabel = uilabel(app.PlottingTab);
            app.MaximumnodesizeEditFieldLabel.Text = 'Maximum node size';
            app.MaximumnodesizeEditField = uieditfield(app.PlottingTab, 'numeric');
            app.MaximumnodesizeEditField.Value = 1;
            app.NodelayoutDropDownLabel = uilabel(app.PlottingTab);
            app.NodelayoutDropDownLabel.Text = 'Node layout';
            app.NodelayoutDropDown = uidropdown(app.PlottingTab);
            app.NodelayoutDropDown.Items = {'Original', 'force', 'subspace'};
            app.NodelayoutDropDown.Value = 'Original';
            app.NodesizescalingDropDownLabel = uilabel(app.PlottingTab);
            app.NodesizescalingDropDownLabel.Text = 'Node size scaling';
            app.NodesizescalingDropDown = uidropdown(app.PlottingTab);
            app.NodesizescalingDropDown.Items = {'Linear', 'Power', 'Square', 'Cube', 'Log2', 'Log10'};
            app.NodesizescalingDropDown.Value = 'Linear';
            app.NodescalingpowerEditFieldLabel = uilabel(app.PlottingTab);
            app.NodescalingpowerEditFieldLabel.Text = 'Node scaling power';
            app.NodescalingpowerEditField = uieditfield(app.PlottingTab, 'numeric');
            app.NodescalingpowerEditField.Value = 1;
            app.AdvPlottingTab = uitab(app.TabGroup);
            app.AdvPlottingTab.Title = 'Adv. Plotting';
            app.ADVANCEDPLOTTINGLabel = uilabel(app.AdvPlottingTab);
            app.ADVANCEDPLOTTINGLabel.Text = 'ADVANCED PLOTTING';
            app.MaximumedgesEditFieldLabel = uilabel(app.AdvPlottingTab);
            app.MaximumedgesEditFieldLabel.Text = 'Maximum edges';
            app.MaximumedgesEditField = uieditfield(app.AdvPlottingTab, 'numeric');
            app.MaximumedgesEditField.ValueDisplayFormat = '%.0f';
            app.MaximumedgesEditField.Value = 10000;
            app.MinedgeweightEditFieldLabel = uilabel(app.AdvPlottingTab);
            app.MinedgeweightEditFieldLabel.Text = 'Min edge weight ';
            app.MinedgeweightEditField = uieditfield(app.AdvPlottingTab, 'numeric');
            app.MinedgeweightEditField.Value = 0.001;
            app.EdgesubsamplingmethodDropDownLabel = uilabel(app.AdvPlottingTab);
            app.EdgesubsamplingmethodDropDownLabel.Text = 'Edge subsampling method';
            app.EdgesubsamplingmethodDropDown = uidropdown(app.AdvPlottingTab);
            app.EdgesubsamplingmethodDropDown.Items = {'highToLow', 'random'};
            app.EdgesubsamplingmethodDropDown.Value = 'highToLow';
            app.KDEHeightEditFieldLabel = uilabel(app.AdvPlottingTab);
            app.KDEHeightEditFieldLabel.Text = 'KDE Height';
            app.KDEwidthforonepointEditFieldLabel = uilabel(app.AdvPlottingTab);
            app.KDEwidthforonepointEditFieldLabel.Text = 'KDE width for one point';
            app.KDEwidthforonepointEditField = uieditfield(app.AdvPlottingTab, 'numeric');
            app.KDEHeightEditField = uieditfield(app.AdvPlottingTab, 'numeric');
            app.KDEHeightEditField.Value = 0.3;
            app.EdgeweightpercentileEditFieldLabel = uilabel(app.AdvPlottingTab);
            app.EdgeweightpercentileEditFieldLabel.Text = 'Edge weight percentile ';
            app.EdgeweightpercentileEditField = uieditfield(app.AdvPlottingTab, 'numeric');
            app.EdgeweightpercentileEditField.Value = 95;
            app.EdgethresholdmethodDropDownLabel = uilabel(app.AdvPlottingTab);
            app.EdgethresholdmethodDropDownLabel.Text = 'Edge threshold method';
            app.EdgethresholdmethodDropDown = uidropdown(app.AdvPlottingTab);
            app.EdgethresholdmethodDropDown.Items = {'Absolute Value', 'Percentile'};
            app.EdgethresholdmethodDropDown.Value = 'Absolute Value';
            app.ColorsTab = uitab(app.TabGroup);
            app.ColorsTab.Title = 'Colors';
            app.colorUITable = uitable(app.ColorsTab);
            app.colorUITable.ColumnName = {'Group'; 'R'; 'G'; 'B'};
            app.colorUITable.RowName = {};
            app.COLORSLabel = uilabel(app.ColorsTab);
            app.COLORSLabel.Text = 'COLORS';
            app.AdvDimensionalityTab = uitab(app.TabGroup);
            app.AdvDimensionalityTab.Title = 'Adv. Dimensionality';
            app.NMFDownsamplingFrequencyHzEditFieldLabel = uilabel(app.AdvDimensionalityTab);
            app.NMFDownsamplingFrequencyHzEditFieldLabel.Text = 'NMF Downsampling Frequency (Hz)';
            app.NMFDownsamplingFrequencyHzEditField = uieditfield(app.AdvDimensionalityTab, 'numeric');
            app.NMFDownsamplingFrequencyHzEditField.Value = 10;
            app.effRankDownsamplingFrequencyHzEditFieldLabel = uilabel(app.AdvDimensionalityTab);
            app.effRankDownsamplingFrequencyHzEditFieldLabel.Text = 'effRank Downsampling Frequency (Hz)';
            app.effRankDownsamplingFrequencyHzEditField = uieditfield(app.AdvDimensionalityTab, 'numeric');
            app.effRankDownsamplingFrequencyHzEditField.Value = 10;
            app.effRankCalculationMethodDropDownLabel = uilabel(app.AdvDimensionalityTab);
            app.effRankCalculationMethodDropDownLabel.Text = 'effRank Calculation Method';
            app.effRankCalculationMethodDropDown = uidropdown(app.AdvDimensionalityTab);
            app.effRankCalculationMethodDropDown.Items = {'Covariance', 'Correlation'};
            app.effRankCalculationMethodDropDown.Value = 'Covariance';
            app.IncludeNMFcomponentsCheckBox = uicheckbox(app.AdvDimensionalityTab);
            app.IncludeNMFcomponentsCheckBox.Text = 'Include NMF components';
            app.DIMENSIONALITYLabel = uilabel(app.AdvDimensionalityTab);
            app.DIMENSIONALITYLabel.Text = 'DIMENSIONALITY';
            app.NodeCartographyTab = uitab(app.TabGroup);
            app.NodeCartographyTab.Title = 'Node Cartography';
            app.hubBoundaryWMdDegEditFieldLabel = uilabel(app.NodeCartographyTab);
            app.hubBoundaryWMdDegEditFieldLabel.Text = 'hubBoundaryWMdDeg';
            app.hubBoundaryWMdDegEditField = uieditfield(app.NodeCartographyTab, 'numeric');
            app.hubBoundaryWMdDegEditField.Value = 0.25;
            app.periPartCoefEditFieldLabel = uilabel(app.NodeCartographyTab);
            app.periPartCoefEditFieldLabel.Text = 'periPartCoef';
            app.periPartCoefEditField = uieditfield(app.NodeCartographyTab, 'numeric');
            app.periPartCoefEditField.Value = 0.525;
            app.proHubpartCoefEditFieldLabel = uilabel(app.NodeCartographyTab);
            app.proHubpartCoefEditFieldLabel.Text = 'proHubpartCoef';
            app.proHubpartCoefEditField = uieditfield(app.NodeCartographyTab, 'numeric');
            app.proHubpartCoefEditField.Value = 0.45;
            app.nonHubconnectorPartCoefEditFieldLabel = uilabel(app.NodeCartographyTab);
            app.nonHubconnectorPartCoefEditFieldLabel.Text = 'nonHubconnectorPartCoef';
            app.nonHubconnectorPartCoefEditField = uieditfield(app.NodeCartographyTab, 'numeric');
            app.nonHubconnectorPartCoefEditField.Value = 0.8;
            app.connectorHubPartCoefEditFieldLabel = uilabel(app.NodeCartographyTab);
            app.connectorHubPartCoefEditFieldLabel.Text = 'connectorHubPartCoef';
            app.connectorHubPartCoefEditField = uieditfield(app.NodeCartographyTab, 'numeric');
            app.connectorHubPartCoefEditField.Value = 0.75;
            app.NODECARTOGRAPHYLabel = uilabel(app.NodeCartographyTab);
            app.NODECARTOGRAPHYLabel.Text = 'NODE CARTOGRAPHY';
            app.AdvConnectivityTab = uitab(app.TabGroup);
            app.AdvConnectivityTab.Title = 'Adv. Connectivity';
            app.ExcludeedgesbelowthresholdCheckBox = uicheckbox(app.AdvConnectivityTab);
            app.ExcludeedgesbelowthresholdCheckBox.Text = 'Exclude edges below threshold';
            app.ExcludeedgesbelowthresholdCheckBox.Value = true;
            app.MinimumnumberofnodesEditFieldLabel = uilabel(app.AdvConnectivityTab);
            app.MinimumnumberofnodesEditFieldLabel.Text = 'Minimum number of nodes';
            app.MinimumnumberofnodesEditField = uieditfield(app.AdvConnectivityTab, 'numeric');
            app.MinimumnumberofnodesEditField.Value = 25;
            app.NetworkmetricstocalculateListBoxLabel = uilabel(app.AdvConnectivityTab);
            app.NetworkmetricstocalculateListBoxLabel.Text = 'Network metrics to calculate';
            app.NetworkmetricstocalculateListBox = uilistbox(app.AdvConnectivityTab);
            app.NetworkmetricstocalculateListBox.Items = {'aN', 'ND', 'EW', 'NS', 'Dens', 'Ci', 'Q', 'nMod', 'Eglob', 'CC', 'PL', 'SW', 'SWw', 'Eloc', 'BC', 'PC', 'PC_raw', 'Cmcblty', 'Z', 'NE', 'effRank', 'num_nnmf_components', 'nComponentRelNS', 'aveControl', 'modalControl'};
            app.NetworkmetricstocalculateListBox.Multiselect = 'on';
            app.NetworkmetricstocalculateListBox.Value = {'aN'};
            app.ADVANCEDCONNECTIVITYLabel = uilabel(app.AdvConnectivityTab);
            app.ADVANCEDCONNECTIVITYLabel.Text = 'ADVANCED CONNECTIVITY';
            app.PipelineTab = uitab(app.TabGroup);
            app.PipelineTab.Title = 'Pipeline';
            app.TimeprocessesCheckBox = uicheckbox(app.PipelineTab);
            app.TimeprocessesCheckBox.Text = 'Time processes';
            app.VerboseLevelDropDownLabel = uilabel(app.PipelineTab);
            app.VerboseLevelDropDownLabel.Text = 'Verbose Level';
            app.VerboseLevelDropDown = uidropdown(app.PipelineTab);
            app.VerboseLevelDropDown.Items = {'High', 'Normal', 'Silent'};
            app.VerboseLevelDropDown.Value = 'Normal';
            app.PIPELINELabel = uilabel(app.PipelineTab);
            app.PIPELINELabel.Text = 'PIPELINE';
            app.StimulationmodeCheckBox = uicheckbox(app.PipelineTab);
            app.StimulationmodeCheckBox.Text = 'Stimulation mode';
            app.FileConversionTab = uitab(app.TabGroup);
            app.FileConversionTab.Title = 'File Conversion';
            app.FileTypeDropDownLabel = uilabel(app.FileConversionTab);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.FileConversionTab);
            app.FileTypeDropDown.Items = {'.raw from Multichannel Systems', '.raw from Axion Maestro', '.h5 from Multichannel Systems'};
            app.FileTypeDropDown.Value = '.raw from Multichannel Systems';
            app.DataFolderEditFieldLabel = uilabel(app.FileConversionTab);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.FileConversionTab, 'text');
            app.RunfileconversionButton = uibutton(app.FileConversionTab, 'state');
            app.RunfileconversionButton.Text = 'Run file conversion';
            app.FileConversionselectButton = uibutton(app.FileConversionTab, 'state');
            app.FileConversionselectButton.Text = 'select';
            app.BatchCSVNameEditFieldLabel = uilabel(app.FileConversionTab);
            app.BatchCSVNameEditFieldLabel.Text = 'Batch CSV Name';
            app.BatchCSVNameEditField = uieditfield(app.FileConversionTab, 'text');
            app.DIVincludedCheckBox = uicheckbox(app.FileConversionTab);
            app.DIVincludedCheckBox.Text = 'DIV included?';
            app.OneGenotypeCheckBox = uicheckbox(app.FileConversionTab);
            app.OneGenotypeCheckBox.Text = 'One group?';
            app.GroupNameEditFieldLabel = uilabel(app.FileConversionTab);
            app.GroupNameEditFieldLabel.Text = 'Group Name';
            app.GroupNameEditField = uieditfield(app.FileConversionTab, 'text');
            app.FILECONVERSIONLabel = uilabel(app.FileConversionTab);
            app.FILECONVERSIONLabel.Text = 'FILE CONVERSION';
            app.SpreadsheetTab = uitab(app.TabGroup);
            app.SpreadsheetTab.Title = 'Spreadsheet';
            app.SpreadsheetFilepathEditFieldLabel = uilabel(app.SpreadsheetTab);
            app.SpreadsheetFilepathEditFieldLabel.Text = 'Spreadsheet Filepath';
            app.SpreadsheetFilepathEditField = uieditfield(app.SpreadsheetTab, 'text');
            app.csvTable = uitable(app.SpreadsheetTab);
            app.csvTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.csvTable.RowName = {};
            app.csvTable.ColumnSortable = true;
            app.csvTable.ColumnEditable = true;
            app.SavespreadsheetButton = uibutton(app.SpreadsheetTab, 'state');
            app.SavespreadsheetButton.Text = 'Save spreadsheet';
            app.LoadspreadsheetButton = uibutton(app.SpreadsheetTab, 'state');
            app.LoadspreadsheetButton.Text = 'Load spreadsheet';
            app.RawDataFolderEditField_2Label = uilabel(app.SpreadsheetTab);
            app.RawDataFolderEditField_2Label.Text = 'Raw Data Folder';
            app.RawDataFolderEditField_2 = uieditfield(app.SpreadsheetTab, 'text');
            app.CreatespreadsheetButton = uibutton(app.SpreadsheetTab, 'state');
            app.CreatespreadsheetButton.Text = 'Create spreadsheet';
            app.SPREADSHEETLabel = uilabel(app.SpreadsheetTab);
            app.SPREADSHEETLabel.Text = 'SPREADSHEET';
            app.CATNAPTab = uitab(app.TabGroup);
            app.CATNAPTab.Title = 'CAT-NAP';
            app.PythonpathEditFieldLabel = uilabel(app.CATNAPTab);
            app.PythonpathEditFieldLabel.Text = 'Python path';
            app.PythonpathEditField = uieditfield(app.CATNAPTab, 'text');
            app.ActivityDropDownLabel = uilabel(app.CATNAPTab);
            app.ActivityDropDownLabel.Text = 'Activity';
            app.ActivityDropDown = uidropdown(app.CATNAPTab);
            app.ActivityDropDown.Items = {'F', 'spks', 'denoised F', 'peaks'};
            app.ActivityDropDown.Value = 'F';
            app.RedodenoisingCheckBox = uicheckbox(app.CATNAPTab);
            app.RedodenoisingCheckBox.Text = 'Re-do denoising';
            app.CATNAPLabel = uilabel(app.CATNAPTab);
            app.CATNAPLabel.Text = 'CAT-NAP';
            app.RemovenodeswithnopeaksCheckBox = uicheckbox(app.CATNAPTab);
            app.RemovenodeswithnopeaksCheckBox.Text = 'Remove nodes with no peaks';
            app.RemovenodeswithnopeaksCheckBox.Value = true;
            app.CellstoplotperrecordingEditFieldLabel = uilabel(app.CATNAPTab);
            app.CellstoplotperrecordingEditFieldLabel.Text = 'Cells to plot per recording';
            app.CellstoplotperrecordingEditField = uieditfield(app.CATNAPTab, 'text');
            app.CellstoplotperrecordingEditField.Tooltip = {'Number of denoised traces to plot. Set either as an interger or to ''all'' to plot all cells.'};
            app.CellstoplotperrecordingEditField.Value = '20';
            app.DenoisingparametersLabel = uilabel(app.CATNAPTab);
            app.DenoisingparametersLabel.Text = 'Denoising parameters';
            app.DenoisingthresholdEditFieldLabel = uilabel(app.CATNAPTab);
            app.DenoisingthresholdEditFieldLabel.Text = 'Denoising threshold';
            app.DenoisingthresholdEditField = uieditfield(app.CATNAPTab, 'numeric');
            app.DenoisingthresholdEditField.Value = 1.3;
            app.TimebeforepeaksEditFieldLabel = uilabel(app.CATNAPTab);
            app.TimebeforepeaksEditFieldLabel.Text = 'Time before peak (s)';
            app.TimebeforepeaksEditField = uieditfield(app.CATNAPTab, 'numeric');
            app.TimebeforepeaksEditField.Value = 1;
            app.TimeafterpeaksEditFieldLabel = uilabel(app.CATNAPTab);
            app.TimeafterpeaksEditFieldLabel.Text = 'Time after peak (s)';
            app.TimeafterpeaksEditField = uieditfield(app.CATNAPTab, 'numeric');
            app.TimeafterpeaksEditField.Value = 2.05;
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.StimulationLabel = uilabel(app.StimulationTab);
            app.StimulationLabel.Text = 'Stimulation';
            app.StimdetectionmethodDropDownLabel = uilabel(app.StimulationTab);
            app.StimdetectionmethodDropDownLabel.Text = 'Stim detection method';
            app.StimdetectionmethodDropDown = uidropdown(app.StimulationTab);
            app.StimdetectionmethodDropDown.Items = {'absPosThreshold', 'absNegThreshold'};
            app.StimdetectionmethodDropDown.Value = 'absPosThreshold';
            app.StimrefractoryperiodsEditFieldLabel = uilabel(app.StimulationTab);
            app.StimrefractoryperiodsEditFieldLabel.Text = 'Stim refractory period (s)';
            app.StimrefractoryperiodsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.StimrefractoryperiodsEditField.Value = 0.5;
            app.AutomaticstimdetectionCheckBox = uicheckbox(app.StimulationTab);
            app.AutomaticstimdetectionCheckBox.Text = 'Automatic stim detection';
            app.AutomaticstimdetectionCheckBox.Value = true;
            app.DetectionthresholdmultiplierEditFieldLabel = uilabel(app.StimulationTab);
            app.DetectionthresholdmultiplierEditFieldLabel.Text = 'Detection threshold / multiplier';
            app.DetectionthresholdmultiplierEditField = uieditfield(app.StimulationTab, 'numeric');
            app.DetectionthresholdmultiplierEditField.Value = -1000;
            app.StimdurationsEditFieldLabel = uilabel(app.StimulationTab);
            app.StimdurationsEditFieldLabel.Text = 'Stim duration (s)';
            app.StimdurationsEditField = uieditfield(app.StimulationTab, 'numeric');
            app.StimdurationsEditField.Value = 0.00012;
            app.PrestimwindowsEditFieldLabel = uilabel(app.StimulationTab);
            app.PrestimwindowsEditFieldLabel.Text = 'Pre-stim window (s)';
            app.PrestimwindowsEditField = uieditfield(app.StimulationTab, 'text');
            app.PrestimwindowsEditField.Value = '[-1.1, -0.1]';
            app.PoststimwindowsEditFieldLabel = uilabel(app.StimulationTab);
            app.PoststimwindowsEditFieldLabel.Text = 'Post-stim window (s)';
            app.PoststimwindowsEditField = uieditfield(app.StimulationTab, 'text');
            app.PoststimwindowsEditField.Value = '[0.1, 1.1]';
            app.StimdurationforplotssEditFieldLabel = uilabel(app.StimulationTab);
            app.StimdurationforplotssEditFieldLabel.Text = 'Stim duration for plots (s)';
            app.StimdurationforplotssEditField = uieditfield(app.StimulationTab, 'numeric');
            app.StimdurationforplotssEditField.Value = 0.1;
            app.StimignorespikeswindowsEditFieldLabel = uilabel(app.StimulationTab);
            app.StimignorespikeswindowsEditFieldLabel.Text = 'Stim ignore-spikes window (s)';
            app.StimignorespikeswindowsEditField = uieditfield(app.StimulationTab, 'text');
            app.StimignorespikeswindowsEditField.Value = '[-0.02, 0.02]';
            app.LaunchstimdetectionappButton = uibutton(app.StimulationTab, 'state');
            app.LaunchstimdetectionappButton.Text = 'Launch stim detection app';
            app.MergeDIVsTab = uitab(app.TabGroup);
            app.MergeDIVsTab.Title = 'Merge DIVs';
            app.SelectedInputsListBoxLabel = uilabel(app.MergeDIVsTab);
            app.SelectedInputsListBoxLabel.Text = 'Selected Inputs';
            app.InputListBox = uilistbox(app.MergeDIVsTab);
            app.AddButton = uibutton(app.MergeDIVsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.RemoveButton = uibutton(app.MergeDIVsTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = '-';
            app.OutputFolderLabel = uilabel(app.MergeDIVsTab);
            app.OutputFolderLabel.Text = 'Output Folder';
            app.OutputFolderEdit = uieditfield(app.MergeDIVsTab, 'text');
            app.OutputFolderEdit.ValueChangedFcn = createCallbackFcn(app, @OutputFolderEditValueChanged, true);
            app.BrowseOutputButton = uibutton(app.MergeDIVsTab, 'push');
            app.BrowseOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutputButton.Text = '...';
            app.OutputFolderNameLabel = uilabel(app.MergeDIVsTab);
            app.OutputFolderNameLabel.Text = 'Output Folder Name';
            app.OutputFolderNameEdit = uieditfield(app.MergeDIVsTab, 'text');
            app.OutputFolderNameEdit.ValueChangedFcn = createCallbackFcn(app, @OutputFolderNameEditValueChanged, true);
            app.RunMergeButton = uibutton(app.MergeDIVsTab, 'push');
            app.RunMergeButton.ButtonPushedFcn = createCallbackFcn(app, @RunMergeButtonPushed, true);
            app.RunMergeButton.Text = 'Run Merge';
            app.RunPipelineButton = uibutton(app.LeftPanel, 'state');
            app.RunPipelineButton.Text = 'Run Pipeline';
            app.AllrequiredparameterssetLampLabel = uilabel(app.LeftPanel);
            app.AllrequiredparameterssetLampLabel.Text = 'All required parameters set';
            app.AllrequiredparameterssetLamp = uilamp(app.LeftPanel);
            app.Hyperlink = uihyperlink(app.LeftPanel);
            app.Hyperlink.URL = 'https://analysis-pipeline.readthedocs.io/en/latest/?badge=latest';
            app.Hyperlink.Text = 'Documentation';
            app.SaveParametersButton = uibutton(app.LeftPanel, 'state');
            app.SaveParametersButton.Text = 'Save Parameters';
            app.LoadParametersButton = uibutton(app.LeftPanel, 'state');
            app.LoadParametersButton.Text = 'Load Parameters';
            app.ViewOutputsButton = uibutton(app.LeftPanel, 'state');
            app.ViewOutputsButton.Text = 'View Outputs';
            app.TestPipelineButton = uibutton(app.LeftPanel, 'state');
            app.TestPipelineButton.Text = 'Test Pipeline';
            app.RightPanel = uipanel(app.GridLayout);
            app.MEANAPStatusTextAreaLabel = uilabel(app.RightPanel);
            app.MEANAPStatusTextAreaLabel.Text = 'MEA-NAP Status';
            app.MEANAPStatusTextArea = uitextarea(app.RightPanel);
            app.MEANAPStatusTextArea.Editable = 'off';
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'mea-nap-logo-transparent.png';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SpikeDetectionMenu = uimenu(app.ContextMenu);
            app.SpikeDetectionMenu.Text = 'Spike Detection';
            app.ConnectivityMenu = uimenu(app.ContextMenu);
            app.ConnectivityMenu.Text = 'Connectivity';
            % Assign app.ContextMenu
            app.GeneralTab.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.RecordingviewerMenu = uimenu(app.ContextMenu2);
            app.RecordingviewerMenu.Text = 'Recording viewer';
            app.StatsviewerMenu = uimenu(app.ContextMenu2);
            app.StatsviewerMenu.Text = 'Stats viewer';
            % Assign app.ContextMenu2
            app.ViewOutputsButton.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEANAPApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end